classdef LOPER_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CompareMultipleButton        matlab.ui.control.Button
        SteadyStatePanel             matlab.ui.container.Panel
        SavecsvafterSteadyStateBeginningButton  matlab.ui.control.Button
        sLabel_2                     matlab.ui.control.Label
        SteadyStateafterLabel        matlab.ui.control.Label
        TemperatureTreshholdLabel    matlab.ui.control.Label
        EditField_7                  matlab.ui.control.NumericEditField
        Spinner                      matlab.ui.control.Spinner
        RegionLabel                  matlab.ui.control.Label
        EditField_8                  matlab.ui.control.EditField
        ExportReNuPrtomatfileButton  matlab.ui.control.Button
        PlotModeButtonGroup          matlab.ui.container.ButtonGroup
        ColormapButton               matlab.ui.control.ToggleButton
        StackedButton                matlab.ui.control.ToggleButton
        FileLabel                    matlab.ui.control.Label
        EditField_6                  matlab.ui.control.EditField
        CreateFigureofTabButton      matlab.ui.control.Button
        FormatFileButton             matlab.ui.control.Button
        txttocsvButton               matlab.ui.control.Button
        SplitButton                  matlab.ui.control.Button
        sLabel                       matlab.ui.control.Label
        DurationLabel                matlab.ui.control.Label
        EditField_4                  matlab.ui.control.NumericEditField
        OpenFileButton               matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        TemperaturesTab              matlab.ui.container.Tab
        TemperatureDropDown          matlab.ui.control.DropDown
        TemperatureDropDownLabel     matlab.ui.control.Label
        EnthalpyTab                  matlab.ui.container.Tab
        SystemDataTab                matlab.ui.container.Tab
        TimeDataTab                  matlab.ui.container.Tab
        frackJkgLabel_2              matlab.ui.control.Label
        EnthalpychangeLabel          matlab.ui.control.Label
        EditField_5                  matlab.ui.control.NumericEditField
        WLabel                       matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        frackJkgLabel                matlab.ui.control.Label
        fraclminLabel                matlab.ui.control.Label
        HeaterPowerLabel             matlab.ui.control.Label
        EnthalpyinLabel              matlab.ui.control.Label
        MassflowLabel                matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        TimeSlider                   matlab.ui.control.Slider
        TimeSliderLabel              matlab.ui.control.Label
        EnergyTab                    matlab.ui.container.Tab
        VapourFractionTab            matlab.ui.container.Tab
        HTCTab                       matlab.ui.container.Tab
        HTCSimulatedTab              matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        %% variables
        rawData;
        Q_dot;
        Temp_wall;
        Temp_wall_outside;
        Enthalpy_IO;
        MassFlow;
        HeaterPower;
        res_Heating;
        HTC;
        Pressure_IO;
        Temp_fluid;
        Enthalpy;
        VapourFrac;
        HTC_sim;
        pos_TC_abs;
        Heat_flux;
        Q_loss;
        File;
        Nu_exp;
        Re;
        Pr;
        timeVect;
        HeaterSet;
        EnthalpyInSet;
        fileOpened;
        totalTime;
        deltaT;
        Path;
        beginSS;
        pos_colormap

        %% tiles
        TimeTabTiles;
        TTTax1;
        TTTax2;
        TTTax3;
        HTCTiles;
        EnthalpyTiles;
        TemperatureTiles;
        SystemDataTiles;
        EnergyBalTiles
        VapourFracTiles;
        HTCTiles_sim;

    end

    properties (Access = private)
        DialogApp % Description
    end

    methods (Access = private)

        %% Plots timeVect Graphs
        function plot_TimeGraphs(app,time_index)

            app.EditField.Value=app.MassFlow(time_index); %Durchfluss
            app.EditField_2.Value=app.EnthalpyInSet(time_index); %Eintrittsenthalpie
            app.EditField_3.Value=app.HeaterPower(time_index); %Verdampferleistung
            app.EditField_5.Value=app.Enthalpy_IO(time_index,2)-app.Enthalpy_IO(time_index,1); %Enthalpieänderung

            plot(app.TTTax1,app.Temp_wall(time_index,:),app.pos_TC_abs,"-o"); hold(app.TTTax1,'on');
            plot(app.TTTax1,app.Temp_wall_outside(time_index,:),app.pos_TC_abs,"-o");
            plot(app.TTTax1,app.Temp_fluid(time_index,:),app.pos_TC_abs,"-o");hold(app.TTTax1,'off');
            legend(app.TTTax1,'T_{wall, inside}','T_{wall, outside}','T_{fluid}');
            ylabel(app.TTTax1,'Position');
            xlabel(app.TTTax1,'°C')
            title(app.TTTax1,'Temperature');
            xlim(app.TTTax1,'padded')
            ylim(app.TTTax1,'tight')
            grid(app.TTTax1);


            plot(app.TTTax2,app.Heat_flux(time_index,:),app.pos_TC_abs,"-o");
            ylabel(app.TTTax2,'Position');
            xlabel(app.TTTax2,'$$\frac{W}{m^{2}}$$',Interpreter='latex')
            title(app.TTTax2,'Heat Flux');
            xlim(app.TTTax2,'padded')
            ylim(app.TTTax2,'tight')
            grid(app.TTTax2);


            plot(app.TTTax3,app.HTC(time_index,:),app.pos_TC_abs,"-o"); hold(app.TTTax3,'on');
            plot(app.TTTax3,app.HTC_sim(time_index,:),app.pos_TC_abs,"-o");
            legend(app.TTTax3,'HTC_{meas}','HTC_{calc}');
            xlabel(app.TTTax3,'$$\frac{W}{m^{2}K}$$',Interpreter='latex');
            ylabel(app.TTTax3,'Position');
            xlim(app.TTTax3,'padded')
            ylim(app.TTTax3,'tight')
            title(app.TTTax3,'Heat Transfer Coefficient');
            grid(app.TTTax3);
            hold(app.TTTax3,'off')
        end

        %% Plots general System Data
        function plot_SystemData(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';

            ax1 = nexttile(Tile);
            plot(ax1,app.timeVect,app.MassFlow*60);
            title(ax1,'Flow Rate');
            ylabel(ax1,'L/min')
            ylim(ax1,'padded')
            xlim(ax1,'tight')
            grid(ax1)

            ax2 = nexttile(Tile);
            plot(ax2,app.timeVect,app.EnthalpyInSet,'DisplayName','Set'); hold(ax2,'on');
            plot(ax2,app.timeVect,app.Enthalpy_IO(:,1),'DisplayName','Measured');
            title(ax2,'Enthalpy in');
            legend(ax2);
            ylabel(ax2,'kJ/kg')
            ylim(ax2,'padded')
            xlim(ax2,'tight')
            grid(ax2)
            hold(ax2,'off');

            ax3 = nexttile(Tile);
            plot(ax3,app.timeVect,app.Pressure_IO(:,1),'DisplayName','In');hold(ax3,'on');
            plot(ax3,app.timeVect,app.Pressure_IO(:,2),'DisplayName','Out')
            legend(ax3);
            title(ax3,'Pressure');
            ylabel(ax3,'bar')
            ylim(ax3,'padded')
            xlim(ax3,'tight')
            grid(ax3)
            hold(ax3,'off');

            ax4 = nexttile(Tile);
            plot(ax4,app.timeVect,app.HeaterSet,'DisplayName','Set'); hold(ax4,'on');
            plot(ax4,app.timeVect,app.HeaterPower,'DisplayName','Measured');
            legend(ax4);
            title(ax4,'Heater Power');
            ylabel(ax4,'W')
            ylim(ax4,'padded')
            xlim(ax4,'tight')
            grid(ax4)
            hold(ax4,'off');
        end

        %% Plots temperatures
        function plot_TemperatureData(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';

            switch app.TemperatureDropDown.Value
                case 'Wall, inside'
                    Temp=app.Temp_wall;
                case 'Wall, outside'
                    Temp=app.Temp_wall_outside;
                case 'Fluid'
                    Temp=app.Temp_fluid;
            end

            switch app.PlotModeButtonGroup.SelectedObject.Text

                case 'Stacked'
                    ax1=nexttile(Tile);
                    plot(ax1,app.timeVect,flip(Temp,2));
                    xlabel(ax1,'Seconds');
                    %  title(ax1,'Temperature_{wall}');
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    ylabel(ax1,'°C','rotation',0,'FontSize',12)
                    legend(ax1,'T_{10}','T_{9}','T_{8}','T_{7}','T_{6}','T_{5}','T_{4}','T_{3}','T_{2}','T_{1}');
                    grid(ax1);
                    hold(ax1,'off');

                case 'Colormap'
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.timeVect,app.pos_colormap,Temp');
                    set(h, 'EdgeColor', 'none');
                    % title(ax1,'Temperature_{wall}');
                    c=colorbar(ax1);
                    ylabel(c,'\circC','rotation',0,'FontSize',12)
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in m')
            end
        end

        %% Plots Enthalpy
        function plot_Enthalpy(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';

            switch app.PlotModeButtonGroup.SelectedObject.Text
                case 'Stacked'
                    ax1=nexttile(Tile);
                    plot(ax1,app.timeVect,flip(app.Enthalpy,2));
                    title(ax1,'Enthalpy')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'$$\frac{kJ}{kg}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    legend(ax1,'h_{10}','h_{9}','h_{8}','h_{7}','h_{6}','h_{5}','h_{4}','h_{3}','h_{2}','h_{1}');
                    grid(ax1);
                case 'Colormap'
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.timeVect,app.pos_colormap,app.Enthalpy');
                    set(h, 'EdgeColor', 'none');
                    c=colorbar(ax1);
                    ylabel(c,'$$\frac{kJ}{kg}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    title(ax1,'Enthalpy')
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in m')
            end

        end

        %% Plots Heat Transfer Coefficient
        function plot_HTC(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';

            switch app.PlotModeButtonGroup.SelectedObject.Text
                case 'Stacked'
                    ax1=nexttile(Tile);
                    plot(ax1,app.timeVect,flip(app.HTC,2)/1000);
                    title (ax1,'Heat Transfer Coefficient');
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'$$\frac{kW}{m^{2}K}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    legend(ax1,'HTC_{10}','HTC_{9}','HTC_{8}','HTC_{7}','HTC_{6}','HTC_{5}','HTC_{4}','HTC_{3}','HTC_{2}','HTC_{1}');
                    grid(ax1);
                case 'Colormap'
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.timeVect,app.pos_colormap,app.HTC'/1000);
                    set(h, 'EdgeColor', 'none');
                    c=colorbar(ax1);
                    ylabel(c,'$$\frac{kW}{m^{2}K}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    title(ax1,'Heat Transfer Coefficient')
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in m')
            end

        end

        %% Plots Simulated Heat Transfer Coefficient
        function plot_HTC_sim(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';

            switch app.PlotModeButtonGroup.SelectedObject.Text
                case 'Stacked'
                    ax1=nexttile(Tile);
                    plot(ax1,app.timeVect,flip(app.HTC_sim,2));
                    title (ax1,'Heat Transfer Coefficient');
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'$$\frac{W}{m^{2}K}$$',Interpreter='latex')
                    ylim(ax1,'padded')
                    xlim(ax1,'tight')
                    legend(ax1,'HTC_{10}','HTC_{9}','HTC_{8}','HTC_{7}','HTC_{6}','HTC_{5}','HTC_{4}','HTC_{3}','HTC_{2}','HTC_{1}');
                    grid(ax1);
                case 'Colormap'
                    ax1=nexttile(Tile);
                    h=pcolor(ax1,app.timeVect,app.pos_colormap,app.HTC_sim');
                    set(h, 'EdgeColor', 'none');
                    c=colorbar(ax1);
                    ylabel(c,'$$\frac{W}{m^{2}K}$$',Interpreter='latex',Rotation=0,FontSize=12)
                    title(ax1,'Heat Transfer Coefficient')
                    set(ax1,'YDir','normal')
                    xlabel(ax1,'Seconds');
                    ylabel(ax1,'h in m')
            end

        end

        %% Plots Heat Loss
        function plot_Heat_Loss(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            ax1=nexttile(Tile);
            plot(ax1,app.timeVect,app.Q_loss);
            title (ax1,'Heat Loss of the system');
            xlabel(ax1,'Seconds');
            ylabel(ax1,'W')
            ylim(ax1,'padded')
            xlim(ax1,'tight')
            grid(ax1,'on');
            hold(ax1,'off');
        end


        %% Plots Vapour Fraction
        function plot_VapourFrac(app,Tile)
            Tile.TileSpacing = 'compact';
            Tile.Padding = 'compact';
            ax1=nexttile(Tile);
            h=pcolor(ax1,app.timeVect,app.pos_colormap,app.VapourFrac');
            set(h, 'EdgeColor', 'none');
            c=colorbar(ax1);
            ylabel(c,'$\dot{x}$', 'Interpreter','latex',Rotation=0,FontSize=12)
            title(ax1,'Vapour Fraction');
            set(ax1,'YDir','normal');
            xlabel(ax1,'Seconds');
            ylabel(ax1,'h in cm');

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
            app.EnthalpyTiles= tiledlayout(app.EnthalpyTab,1,1);
            app.SystemDataTiles = tiledlayout(app.SystemDataTab,4,1);
            app.TimeTabTiles = tiledlayout(app.Panel,1,3);
            app.TimeTabTiles.TileSpacing = 'compact';
            app.TimeTabTiles.Padding = 'compact';
            app.EnergyBalTiles = tiledlayout(app.EnergyTab,1,1);
            app.VapourFracTiles = tiledlayout(app.VapourFractionTab,1,1);
            app.HTCTiles = tiledlayout(app.HTCTab,1,1);
            app.HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);

        end

        % Button pushed function: OpenFileButton
        function LeftPanelButtonDown(app, event)
            close all
            % close (findall(0,'Type','figure'))
            %% Checks if requirements (SteamTables, File Format) are fullfilled
            % Check if XSteam or refprop is installed
            % On Windows folder might not be added automaticaly to MATLAB Path
            if ispc
                if ~exist('refpropm.m','file')
                    msgbox(["ERROR!";"refprop not found";"Please add to path or install"],"Error","error");
                    return;
                end
            else
                addons=matlab.addons.installedAddons;
                if~sum(contains(addons.Name, 'X Steam, Thermodynamic properties of water and steam.'))
                    msgbox(["ERROR!";" XSteam not found";"Please install"],"Error","error");
                    return;
                end
                clear addons
            end
            if ~contains(struct2array(ver), 'Statistics and Machine Learning Toolbox')
                msgbox(["ERROR!";" Statistics and Machine Learning Toolbox reqired"],"Error","error");
                return;
            end
            if ~contains(struct2array(ver), 'Image Processing Toolbox')
                msgbox(["ERROR!";"Image Processing Toolbox required"],"Error","error");
                return;
            end

            % Opens File
            [app.File,app.Path] = uigetfile('*.csv','Select the measurement data');%open file browser
            full_Path=fullfile(app.Path,app.File);

            if isequal(app.File,0)
                return;
            end

            rawData=readtable(full_Path); %readsData
            %clear File Path full_Path;


            % Check if . (dot) is used in decimals
            if(~isa(rawData.deltaTInS(1),'double'))
                msgbox(["ERROR!";"File not supported";"Decimals must use a . (dot)"],"Error","error");
                return;
            end

            % Check if file contains multiple runs
            NewStart=find(rawData.deltaTInS==0);
            if (length(NewStart)>1)
                msgbox(["ERROR!";"File contains multiple runs";"Please use Split first"],"Error","error");
                return;
            end
            clear NewStart

            delete(app.DialogApp)
            app.OpenFileButton.Text = '';
            app.OpenFileButton.Icon = '332-loader-3.gif';

            %% runs Analysis
            GUI=1;
            runAnalysis;

            app.OpenFileButton.Icon = '';
            % Change button name
            app.OpenFileButton.Text = 'Open File';
            close all;

            app.fileOpened=isprop(app, 'rawData');

            if ~app.fileOpened
                return
            end

            %% Creates Layout
            app.TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
            app.EnthalpyTiles= tiledlayout(app.EnthalpyTab,1,1);
            app.SystemDataTiles = tiledlayout(app.SystemDataTab,4,1);
            app.TimeTabTiles = tiledlayout(app.Panel,1,3);
            app.TimeTabTiles.TileSpacing = 'compact';
            app.TimeTabTiles.Padding = 'compact';
            app.EnergyBalTiles = tiledlayout(app.EnergyTab,1,1);
            app.VapourFracTiles = tiledlayout(app.VapourFractionTab,1,1);
            app.HTCTiles = tiledlayout(app.HTCTab,1,1);
            app.HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);

            %% sets Variables
            app.rawData=rawData;
            app.timeVect=timeVect;
            app.Temp_wall=Temp_wall;
            app.Enthalpy_IO=Enthalpy_IO;
            app.MassFlow=MassFlow;
            app.HeaterPower=HeaterPower;
            app.HeaterSet=HeaterSet;
            app.EnthalpyInSet=EnthalpyInSet;
            app.res_Heating=res_Heating;
            app.HTC=HTC;
            app.Pressure_IO=Pressure_IO;
            app.Temp_fluid=Temp_fluid;
            app.Enthalpy=Enthalpy;
            app.VapourFrac=VapourFrac;
            app.HTC_sim=HTC_sim;
            app.pos_TC_abs=pos_TC_abs;
            app.Heat_flux=Heat_flux;
            app.Q_loss=Q_loss;
            app.Temp_wall_outside=Temp_wall_outside;
            app.File=app.File(1:end-4);
            app.Re=Re;
            app.Nu_exp=Nu_exp;
            app.Pr=Pr;
            app.totalTime=totalTime;
            app.deltaT=deltaT;
            app.pos_colormap=[0 0.09 0.24 0.39 0.57 0.69 0.74 0.79 0.84 0.89];

            %% shows Sidebar Data
            app.EditField_6.Value=app.File; % Current file
            app.EditField_4.Value=totalTime; %Dauer

            if any(app.VapourFrac>0,'all')
                app.EditField_8.Value='2 Phase';
            else
                app.EditField_8.Value='1 Phase';
            end

            %% Draws Plots
            plot_TemperatureData(app,app.TemperatureTiles);
            %plot_HeatChangeData(app,app.EnthalpyTiles,0);
            plot_Enthalpy(app,app.EnthalpyTiles);
            plot_SystemData(app,app.SystemDataTiles);
            plot_HTC(app,app.HTCTiles);
            plot_Heat_Loss(app,app.EnergyBalTiles)
            plot_VapourFrac(app,app.VapourFracTiles);
            plot_HTC_sim(app,app.HTCTiles_sim);

            %% Time Tab Stuff
            app.TimeSlider.Limits = [0 totalTime];
            app.TimeSlider.MajorTicks=app.timeVect(1:2:end);
            app.TimeSlider.MinorTicks=app.timeVect(2:2:end);
            app.TTTax1=nexttile(app.TimeTabTiles);
            app.TTTax2=nexttile(app.TimeTabTiles);
            app.TTTax3=nexttile(app.TimeTabTiles);
            plot_TimeGraphs(app,1);

            SpinnerValueChanged(app, event);
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = round(app.TimeSlider.Value,0);
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - app.timeVect));
            % move the slider to that option
            event.Source.Value = app.timeVect(minIdx);
            plot_TimeGraphs(app,minIdx);
        end

        % Button pushed function: SplitButton
        function SplitButtonPushed(app, event)
            %% Splits multiple runs into seperate files
            splitIntoSingleRuns;
        end

        % Button pushed function: FormatFileButton
        function FormatFileButtonPushed(app, event)
            %% Replaces , (comma) with . (dot) and   (tab) with  ;  (semicolon) in Decimals in the CSV file
            replaceCommaDot;
        end

        % Button pushed function: txttocsvButton
        function txttocsvButtonPushed(app, event)
            %% Converts multi run TXT file into single run CSV files and replaces , (comma) with . (dot)
            reformatFile;
        end

        % Button pushed function: CreateFigureofTabButton
        function CreateFigureofTabButtonPushed(app, event)
            if app.fileOpened
                switch app.TabGroup.SelectedTab.Title

                    case 'Temperatures'
                        fig=figure;
                        TemperatureTiles = tiledlayout(fig,1,1);
                        plot_TemperatureData(app,TemperatureTiles);

                    case 'Enthalpy'
                        fig=figure;
                        EnthalpyTiles= tiledlayout(fig,1,1);
                        plot_Enthalpy(app,EnthalpyTiles);

                    case 'System Data'
                        fig=figure;
                        SystemDataTiles = tiledlayout(fig,4,1);
                        plot_SystemData(app,SystemDataTiles);

                    case 'Time Data'
                        msgbox('Figure creation not supported for this tab');

                    case 'Energy'
                        fig=figure;
                        EnergyBalTiles = tiledlayout(fig,1,1);
                        plot_Heat_Loss(app,EnergyBalTiles);

                    case 'Vapour Fraction'
                        fig=figure;
                        VapourFracTiles = tiledlayout(fig,1,1);
                        plot_VapourFrac(app,VapourFracTiles)

                    case 'HTC'
                        fig=figure;
                        HTCTiles = tiledlayout(fig,1,1);
                        plot_HTC(app,HTCTiles);
                        
                    case 'HTC Simulated'
                        fig=figure;
                        HTCTiles_sim = tiledlayout(fig,1,1);
                        plot_HTC_sim(app,HTCTiles_sim)
                end
            end
        end

        % Selection changed function: PlotModeButtonGroup
        function PlotModeButtonGroupSelectionChanged(app, event)

            if app.fileOpened
                app.TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
                app.EnthalpyTiles= tiledlayout(app.EnthalpyTab,1,1);
                app.HTCTiles = tiledlayout(app.HTCTab,1,1);
                app.HTCTiles_sim = tiledlayout(app.HTCSimulatedTab,1,1);

                plot_TemperatureData(app,app.TemperatureTiles);
                plot_Enthalpy(app,app.EnthalpyTiles);
                plot_HTC(app,app.HTCTiles);
                plot_HTC_sim(app,app.HTCTiles_sim);
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            if app.fileOpened
                limit = app.Spinner.Value;

                %                 tempdiff=diff(app.Temp_wall);
                %                 for i=1:length(tempdiff)
                %                     rem_sum=sum(tempdiff(i:end,:));
                %                     if max(abs(rem_sum))<limit
                %                         app.beginSS=i;
                %                         break;
                %                     end
                %                 end
                %
                %                 if i<0.9*length(app.Temp_wall) && (max(range(app.Temp_wall))<=2*limit)
                %
                %                     app.EditField_7.Value=app.timeVect(i);
                %                     remTime=(app.totalTime-app.timeVect(i));
                %                     app.DialogApp = summaryLOPER(app, i, remTime);
                %                   dispValues(app.DialogApp,i,remTime);
                %                 else
                %                     app.EditField_7.Value=Inf;
                %                 end

                for i=1:length(app.Temp_wall)
                    maxChng=max(range(app.Temp_wall(i:end,:)));
                    if maxChng<2*limit
                        app.beginSS=i;
                        break;
                    end
                end

                if i<0.9*length(app.Temp_wall)
                    app.EditField_7.Value=app.timeVect(i);
                    app.SavecsvafterSteadyStateBeginningButton.Enable='on';
                    if i<0.5*length(app.Temp_wall)
                        remTime=(app.totalTime-app.timeVect(i));
                        app.DialogApp = LOPER_Summary(app, i, remTime);
                        dispValues(app.DialogApp,i,remTime);
                    else
                        delete(app.DialogApp)
                    end
                else
                    delete(app.DialogApp)
                    app.SavecsvafterSteadyStateBeginningButton.Enable='off';
                    app.EditField_7.Value=Inf;
                end

            end
        end

        % Button pushed function: ExportReNuPrtomatfileButton
        function ExportReNuPrtomatfileButtonPushed(app, event)

            if not(isfolder('matFolder'))
                mkdir('matFolder')
            end

            if app.fileOpened
                name="matFolder/"+ app.File+".mat";
                Nu_exp=app.Nu_exp;
                Re=app.Re;
                Pr=app.Pr;
                save(name,'Nu_exp','Re','Pr');
            end
        end

        % Button pushed function: SavecsvafterSteadyStateBeginningButton
        function SavecsvafterSteadyStateBeginningButtonPushed(app, event)
            name=app.File+"_seady.csv";
            name=app.Path + name;
            beginSS=find(app.rawData.deltaTInS==app.timeVect(app.beginSS))
            rawData_split=app.rawData(beginSS:end,:);
            time=0:height(rawData_split)-1;
            rawData_split.deltaTInS=time'/10;
            writetable(rawData_split,name,'Delimiter',';');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all
            delete(app.DialogApp)
            delete(app)

        end

        % Value changed function: TemperatureDropDown
        function TemperatureDropDownValueChanged(app, event)
            app.TemperatureTiles = tiledlayout(app.TemperaturesTab,1,1);
            plot_TemperatureData(app,app.TemperatureTiles);
        end

        % Button pushed function: CompareMultipleButton
        function CompareMultipleButtonPushed(app, event)
            plotMult;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1053, 1053};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {112, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenFileButton = uibutton(app.LeftPanel, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.OpenFileButton.Text = 'Open File';
            app.EditField_4 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_4.Editable = 'off';
            app.DurationLabel = uilabel(app.LeftPanel);
            app.DurationLabel.Text = 'Duration';
            app.sLabel = uilabel(app.LeftPanel);
            app.sLabel.Text = 's';
            app.SplitButton = uibutton(app.LeftPanel, 'push');
            app.SplitButton.ButtonPushedFcn = createCallbackFcn(app, @SplitButtonPushed, true);
            app.SplitButton.Text = 'Split';
            app.txttocsvButton = uibutton(app.LeftPanel, 'push');
            app.txttocsvButton.ButtonPushedFcn = createCallbackFcn(app, @txttocsvButtonPushed, true);
            app.txttocsvButton.Text = '.txt to .csv';
            app.FormatFileButton = uibutton(app.LeftPanel, 'push');
            app.FormatFileButton.ButtonPushedFcn = createCallbackFcn(app, @FormatFileButtonPushed, true);
            app.FormatFileButton.Text = 'Format File';
            app.CreateFigureofTabButton = uibutton(app.LeftPanel, 'push');
            app.CreateFigureofTabButton.ButtonPushedFcn = createCallbackFcn(app, @CreateFigureofTabButtonPushed, true);
            app.CreateFigureofTabButton.Text = {'Create Figure '; 'of Tab'};
            app.EditField_6 = uieditfield(app.LeftPanel, 'text');
            app.EditField_6.Editable = 'off';
            app.FileLabel = uilabel(app.LeftPanel);
            app.FileLabel.Text = 'File';
            app.PlotModeButtonGroup = uibuttongroup(app.LeftPanel);
            app.PlotModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotModeButtonGroupSelectionChanged, true);
            app.PlotModeButtonGroup.Title = 'Plot Mode';
            app.StackedButton = uitogglebutton(app.PlotModeButtonGroup);
            app.StackedButton.Text = 'Stacked';
            app.StackedButton.Value = true;
            app.ColormapButton = uitogglebutton(app.PlotModeButtonGroup);
            app.ColormapButton.Text = 'Colormap';
            app.ExportReNuPrtomatfileButton = uibutton(app.LeftPanel, 'push');
            app.ExportReNuPrtomatfileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReNuPrtomatfileButtonPushed, true);
            app.ExportReNuPrtomatfileButton.Text = {'Export Re, Nu, '; 'Pr to .mat file'};
            app.EditField_8 = uieditfield(app.LeftPanel, 'text');
            app.EditField_8.Editable = 'off';
            app.RegionLabel = uilabel(app.LeftPanel);
            app.RegionLabel.Text = 'Region';
            app.SteadyStatePanel = uipanel(app.LeftPanel);
            app.SteadyStatePanel.Tooltip = {'Summary Window only opens if SteSta is longer than 1/2 total Duration'};
            app.SteadyStatePanel.Title = 'Steady State';
            app.Spinner = uispinner(app.SteadyStatePanel);
            app.Spinner.Step = 0.5;
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueDisplayFormat = '%.1f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Tooltip = {'Maximum Temperature Change over Time for Steady State Detection'};
            app.Spinner.Value = 1;
            app.EditField_7 = uieditfield(app.SteadyStatePanel, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Tooltip = {'If Steady State occurs within the last 10% of the measurements it is disregarded'};
            app.TemperatureTreshholdLabel = uilabel(app.SteadyStatePanel);
            app.TemperatureTreshholdLabel.Tooltip = {'Maximum Temperature Change over Time for Steady State Detection'};
            app.TemperatureTreshholdLabel.Text = {'Temperature'; 'Treshhold'};
            app.SteadyStateafterLabel = uilabel(app.SteadyStatePanel);
            app.SteadyStateafterLabel.Tooltip = {'If Steady State occurs within the last 10% of the measurements it is disregarded'};
            app.SteadyStateafterLabel.Text = {'Steady State '; 'after'};
            app.sLabel_2 = uilabel(app.SteadyStatePanel);
            app.sLabel_2.Tooltip = {'Maximum Temperature Change over Time for Steady State Detection'};
            app.sLabel_2.Text = 's';
            app.SavecsvafterSteadyStateBeginningButton = uibutton(app.SteadyStatePanel, 'push');
            app.SavecsvafterSteadyStateBeginningButton.ButtonPushedFcn = createCallbackFcn(app, @SavecsvafterSteadyStateBeginningButtonPushed, true);
            app.SavecsvafterSteadyStateBeginningButton.Text = {'Save .csv after'; 'Steady State'; 'Beginning'};
            app.CompareMultipleButton = uibutton(app.LeftPanel, 'push');
            app.CompareMultipleButton.ButtonPushedFcn = createCallbackFcn(app, @CompareMultipleButtonPushed, true);
            app.CompareMultipleButton.Text = {'Compare'; 'Multiple'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TemperaturesTab = uitab(app.TabGroup);
            app.TemperaturesTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TemperaturesTab.Title = 'Temperatures';
            app.TemperatureDropDownLabel = uilabel(app.TemperaturesTab);
            app.TemperatureDropDownLabel.Text = 'Temperature';
            app.TemperatureDropDown = uidropdown(app.TemperaturesTab);
            app.TemperatureDropDown.Items = {'Wall, inside', 'Wall, outside', 'Fluid'};
            app.TemperatureDropDown.ValueChangedFcn = createCallbackFcn(app, @TemperatureDropDownValueChanged, true);
            app.TemperatureDropDown.Value = 'Wall, inside';
            app.EnthalpyTab = uitab(app.TabGroup);
            app.EnthalpyTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.EnthalpyTab.Title = 'Enthalpy';
            app.SystemDataTab = uitab(app.TabGroup);
            app.SystemDataTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SystemDataTab.Title = 'System Data';
            app.TimeDataTab = uitab(app.TabGroup);
            app.TimeDataTab.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TimeDataTab.Title = 'Time Data';
            app.TimeSliderLabel = uilabel(app.TimeDataTab);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.TimeDataTab);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.EditField = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField_3.Editable = 'off';
            app.MassflowLabel = uilabel(app.TimeDataTab);
            app.MassflowLabel.Text = 'Massflow';
            app.EnthalpyinLabel = uilabel(app.TimeDataTab);
            app.EnthalpyinLabel.Text = 'Enthalpy in';
            app.HeaterPowerLabel = uilabel(app.TimeDataTab);
            app.HeaterPowerLabel.Text = 'Heater Power';
            app.fraclminLabel = uilabel(app.TimeDataTab);
            app.fraclminLabel.Interpreter = 'latex';
            app.fraclminLabel.Text = '\(\frac{l}{min}\)';
            app.frackJkgLabel = uilabel(app.TimeDataTab);
            app.frackJkgLabel.Interpreter = 'latex';
            app.frackJkgLabel.Text = '\(\frac{kJ}{kg}\)';
            app.Panel = uipanel(app.TimeDataTab);
            app.WLabel = uilabel(app.TimeDataTab);
            app.WLabel.Text = 'W';
            app.EditField_5 = uieditfield(app.TimeDataTab, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EnthalpychangeLabel = uilabel(app.TimeDataTab);
            app.EnthalpychangeLabel.Text = 'Enthalpy change';
            app.frackJkgLabel_2 = uilabel(app.TimeDataTab);
            app.frackJkgLabel_2.Interpreter = 'latex';
            app.frackJkgLabel_2.Text = '\(\frac{kJ}{kg}\)';
            app.EnergyTab = uitab(app.TabGroup);
            app.EnergyTab.Title = 'Energy';
            app.VapourFractionTab = uitab(app.TabGroup);
            app.VapourFractionTab.Title = 'Vapour Fraction';
            app.HTCTab = uitab(app.TabGroup);
            app.HTCTab.Title = 'HTC';
            app.HTCSimulatedTab = uitab(app.TabGroup);
            app.HTCSimulatedTab.Title = 'HTC Simulated';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOPER_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end