classdef LOPER_Summary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Switch                          matlab.ui.control.Switch
        HTCinfrackWm2KLabel             matlab.ui.control.Label
        dotqinfrackWm2Label             matlab.ui.control.Label
        TinCLabel                       matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        PressureEditField               matlab.ui.control.NumericEditField
        PressureEditFieldLabel          matlab.ui.control.Label
        barLabel                        matlab.ui.control.Label
        ExportValuesButton              matlab.ui.control.Button
        WLabel_2                        matlab.ui.control.Label
        HeatLossEditField               matlab.ui.control.NumericEditField
        HeatLossEditFieldLabel          matlab.ui.control.Label
        frackgminLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        frackJkgLabel                   matlab.ui.control.Label
        EnthalpyinEditField             matlab.ui.control.NumericEditField
        EnthalpyinEditFieldLabel        matlab.ui.control.Label
        frackgsLabel                    matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        SteadyStatedetectedonEditField  matlab.ui.control.EditField
        SteadyStatedetectedonEditFieldLabel  matlab.ui.control.Label
        KEditField                      matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        withpmLabel                     matlab.ui.control.Label
        ExportFigureButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        HeaterPowerEditField            matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel       matlab.ui.control.Label
        MassflowEditField               matlab.ui.control.NumericEditField
        MassflowEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        callingApp % Description
        HeaterPower_mean
        HeatLoss_mean
        Massflow_mean
        EnthalpyIn_mean
        Temp_fluid_mean
        Temp_wall_mean
        Temp_wall_outside_mean
        Heat_flux_mean
        HTC_mean
        HTC_sim_mean
        VapourFrac_mean
        index
        Pressure_mean
        Enthalpy_mean
    end

    methods (Access = public)

        function  dispValues(app,i,dur)
            app.index=i;
            app.UITable.RowName='numbered';
            app.Temp_fluid_mean=mean(app.callingApp.Temp_fluid(i:end,:))';
            app.Temp_wall_mean=mean(app.callingApp.Temp_wall(app.index:end,:))';
            app.Temp_wall_outside_mean=mean(app.callingApp.Temp_wall_outside(app.index:end,:))';
            app.Heat_flux_mean=mean(app.callingApp.Heat_flux(i:end,:))';
            app.HTC_mean=mean(app.callingApp.HTC(i:end,:))';
            app.VapourFrac_mean=mean(app.callingApp.VapourFrac(i:end,:))';
            app.HeaterPower_mean=mean(app.callingApp.HeaterPower(i:end));
            app.HeatLoss_mean=mean(app.callingApp.Q_loss(i:end),'omitnan');
            app.Massflow_mean=mean(app.callingApp.MassFlow(i:end));
            app.EnthalpyIn_mean=mean(app.callingApp.Enthalpy_IO(i:end,1));
            app.Pressure_mean=mean(app.callingApp.Pressure_IO(i:end,:),'all');
            app.Enthalpy_mean=mean(app.callingApp.Enthalpy(i:end,:))';
            app.HTC_sim_mean=mean(app.callingApp.HTC_sim(i:end,:))';

            app.HeaterPowerEditField.Value=app.HeaterPower_mean;
            app.HeatLossEditField.Value=app.HeatLoss_mean;
            app.MassflowEditField.Value=app.Massflow_mean;
            app.EditField.Value=app.Massflow_mean*60;
            app.DurationEditField.Value=dur;
            app.EnthalpyinEditField.Value=app.EnthalpyIn_mean;
            app.SteadyStatedetectedonEditField.Value=app.callingApp.File;
            app.KEditField.Value=app.callingApp.Spinner.Value;
            app.PressureEditField.Value=app.Pressure_mean;
            app.UITable.Data=flip(table(app.Temp_wall_mean,app.Temp_wall_outside_mean,app.Temp_fluid_mean, app.Heat_flux_mean./1000,app.Enthalpy_mean,...
                                                                                        app.HTC_mean./1000,app.HTC_sim_mean./1000, app.VapourFrac_mean));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, i, dur)
            %app.UITable.ColumnName={'Temp (fluid)'; 'Heat Flux (kW/m2)'; 'HTC (kW/m2K)';'Vapour Fraction'};

            app.callingApp=caller;
            dispValues(app,i,dur);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %close(findall(0,'Type','figure','Name','Summary of Data'))
            delete(app)

        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            name=app.callingApp.File + "_sum.jpg";
            exportapp(app.UIFigure,name)
        end

        % Button pushed function: ExportValuesButton
        function ExportValuesButtonPushed(app, event)
            if not(isfolder('SteadyStateFolder'))
                mkdir('SteadyStateFolder')
            end

            name="SteadyStateFolder/"+ app.callingApp.File+".mat";
            Temp_wall_mean=mean(app.callingApp.Temp_wall(app.index:end,:))';
            Temp_wall_outside_mean=mean(app.callingApp.Temp_wall_outside(app.index:end,:))';
            Temp_fluid_mean=app.Temp_fluid_mean;
            HeaterPower_mean=app.HeaterPower_mean;
            HeatLoss_mean=app.HeatLoss_mean;
            Massflow_mean=app.Massflow_mean;
            EnthalpyIn_mean=app.EnthalpyIn_mean;
            Heat_flux_mean=app.Heat_flux_mean;
            HTC_sim_mean=app.HTC_sim_mean;
            VapourFrac_mean=app.VapourFrac_mean;
            Pressure_mean=app.Pressure_mean;
            Enthalpy_mean=app.Enthalpy_mean;
            HTC_mean=app.HTC_mean;
            save(name,'Temp_wall_mean','Temp_wall_outside_mean','Temp_fluid_mean','Enthalpy_mean','Heat_flux_mean','HTC_mean','HTC_sim_mean', 'HeaterPower_mean',...
                'HeatLoss_mean','Massflow_mean','EnthalpyIn_mean','VapourFrac_mean','Pressure_mean');

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            close(findall(0,'Type','figure','Name','Summary of Data'))
            pos_TC_abs=app.callingApp.pos_TC_abs;
            fig1=figure('Name','Summary of Data');

            if app.Switch.Value=='horz'
               %% Horizontal Plot 
            TimeTabTiles = tiledlayout(fig1,1,3);
            TimeTabTiles.TileSpacing = 'compact';
            TimeTabTiles.Padding = 'compact';
            title(TimeTabTiles,app.callingApp.File,'Interpreter', 'none');

            ax1=nexttile(TimeTabTiles);
            plot(ax1,app.Temp_wall_mean,pos_TC_abs,"-o"); hold(ax1,'on');
            plot(ax1,app.Temp_wall_outside_mean,pos_TC_abs,"-o");
            plot(ax1,app.Temp_fluid_mean,pos_TC_abs,"-o");hold(ax1,'off');
            legend(ax1,'T_{wall, inside}','T_{wall, outside}','T_{fluid}');
            ylabel(ax1,'Position');
            xlabel(ax1,'°C')
            title(ax1,'Temperature');
            xlim(ax1,'padded')
            ylim(ax1,'tight')
            grid(ax1);

            ax2=nexttile(TimeTabTiles);
            plot(ax2,app.Heat_flux_mean/1000,pos_TC_abs,"-o");
            ylabel(ax2,'Position');
            xlabel(ax2,'$$\frac{kW}{m^{2}}$$',Interpreter='latex')
            title(ax2,'Heat Flux');
            xlim(ax2,'padded')
            ylim(ax2,'tight')
            grid(ax2);

            ax3=nexttile(TimeTabTiles);
            plot(ax3,app.HTC_mean/1000,pos_TC_abs,"-o"); hold(ax3,'on');
            plot(ax3,app.HTC_sim_mean/1000,pos_TC_abs,"-o");
            legend(ax3,'HTC_{meas}','HTC_{sim}');
            %legend(ax3,'HTC_{meas}');
            xlabel(ax3,'$$\frac{kW}{m^{2}K}$$',Interpreter='latex');
            ylabel(ax3,'Position');
            xlim(ax3,'padded')
            ylim(ax3,'tight')
            title(ax3,'Heat Transfer Coefficient');
            grid(ax3);
            hold(ax3,'off')
            else
%% Vertical Plot
            TimeTabTiles = tiledlayout(fig1,3,1);
            TimeTabTiles.TileSpacing = 'compact';
            TimeTabTiles.Padding = 'compact';
            title(TimeTabTiles,app.callingApp.File,'Interpreter', 'none');

            ax1=nexttile(TimeTabTiles);
            plot(ax1,pos_TC_abs,app.Temp_wall_mean,"-o"); hold(ax1,'on');
            plot(ax1,pos_TC_abs,app.Temp_wall_outside_mean,"-o");
            plot(ax1,pos_TC_abs,app.Temp_fluid_mean,"-o");hold(ax1,'off');
            legend(ax1,'T_{wall, inside}','T_{wall, outside}','T_{fluid}');
            xlabel(ax1,'Position');
            ylabel(ax1,'°C')
            title(ax1,'Temperature');
            %xlim(ax1,'padded')
            ylim(ax1,'padded')
            grid(ax1);

            ax2=nexttile(TimeTabTiles);
            plot(ax2,pos_TC_abs,app.Heat_flux_mean/1000,"-o");
            xlabel(ax2,'Position');
            ylabel(ax2,'$$\frac{kW}{m^{2}}$$',Interpreter='latex')
            title(ax2,'Heat Flux');
            %xlim(ax2,'padded')
            ylim(ax2,'padded')
            grid(ax2);

            ax3=nexttile(TimeTabTiles);
            plot(ax3,pos_TC_abs,app.HTC_mean/1000,"-o"); hold(ax3,'on');
            plot(ax3,pos_TC_abs,app.HTC_sim_mean/1000,"-o");
            legend(ax3,'HTC_{meas}','HTC_{sim}');
            %legend(ax3,'HTC_{meas}');
            ylabel(ax3,'$$\frac{kW}{m^{2}K}$$',Interpreter='latex');
            xlabel(ax3,'Position');
            %xlim(ax3,'padded')
            ylim(ax3,'padded')
            title(ax3,'Heat Transfer Coefficient');
            grid(ax3);
            hold(ax3,'off')

            end

            fig2=figure('Name','Summary of Data');
            Tiles = tiledlayout(fig2,2,1);
            Tiles.TileSpacing = 'compact';
            Tiles.Padding = 'compact';
            title(Tiles,app.callingApp.File,'Interpreter', 'none');

            ax1=nexttile(Tiles);
            plot(ax1,app.Enthalpy_mean,app.Temp_wall_mean,"-o"); hold(ax1,'on');
            plot(ax1,app.Enthalpy_mean,app.Temp_wall_outside_mean,"-o");
            plot(ax1,app.Enthalpy_mean,app.Temp_fluid_mean,"-o");hold(ax1,'off');
            legend(ax1,'T_{wall, inside}','T_{wall, outside}','T_{fluid}');
            ylabel(ax1,'°C');
            xlabel(ax1,'$$h\; in\; \frac{kJ}{kg}$$',Interpreter='latex');
            title(ax1,'Temperature');
            xlim(ax1,'padded')
            ylim(ax1,'padded')
            grid(ax1);

            ax2=nexttile(Tiles);
            plot(ax2,app.Enthalpy_mean,app.HTC_mean/1000,"-o"); hold(ax2,'on');
            plot(ax2,app.Enthalpy_mean,app.HTC_sim_mean/1000,"-o");
            legend(ax2,'HTC_{meas}','HTC_{sim}');
            %legend(ax2,'HTC_{meas}');
            ylabel(ax2,'$$\alpha\; in\; \frac{kW}{m^{2}K}$$',Interpreter='latex');
            xlabel(ax2,'$$h\; in\; \frac{kJ}{kg}$$',Interpreter='latex');
            xlim(ax2,'padded')
            ylim(ax2,'padded')
            title(ax2,'Heat Transfer Coefficient');
            grid(ax2);
            hold(ax2,'off')


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MassflowEditFieldLabel = uilabel(app.UIFigure);
            app.MassflowEditFieldLabel.Text = 'Massflow';
            app.MassflowEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassflowEditField.Editable = 'off';
            app.HeaterPowerEditFieldLabel = uilabel(app.UIFigure);
            app.HeaterPowerEditFieldLabel.Text = 'Heater Power';
            app.HeaterPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeaterPowerEditField.Editable = 'off';
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'T_wall,i'; 'T_wall,o'; 'T_fluid'; 'Heat Flux'; 'Enthalpy'; 'HTC'; 'HTC_sim'; 'Vapour Fraction'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.UITable.Tooltip = {''};
            app.ExportFigureButton = uibutton(app.UIFigure, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.withpmLabel = uilabel(app.UIFigure);
            app.withpmLabel.Interpreter = 'latex';
            app.withpmLabel.Text = 'with $\pm$';
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'latex';
            app.Label.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.Editable = 'off';
            app.SteadyStatedetectedonEditFieldLabel = uilabel(app.UIFigure);
            app.SteadyStatedetectedonEditFieldLabel.Text = 'Steady State detected on';
            app.SteadyStatedetectedonEditField = uieditfield(app.UIFigure, 'text');
            app.SteadyStatedetectedonEditField.Editable = 'off';
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Interpreter = 'latex';
            app.sLabel_2.Text = 's';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Interpreter = 'latex';
            app.WLabel.Text = 'W';
            app.frackgsLabel = uilabel(app.UIFigure);
            app.frackgsLabel.Interpreter = 'latex';
            app.frackgsLabel.Text = '$\frac{kg}{s}$';
            app.EnthalpyinEditFieldLabel = uilabel(app.UIFigure);
            app.EnthalpyinEditFieldLabel.Text = 'Enthalpy in';
            app.EnthalpyinEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnthalpyinEditField.Editable = 'off';
            app.frackJkgLabel = uilabel(app.UIFigure);
            app.frackJkgLabel.Interpreter = 'latex';
            app.frackJkgLabel.Text = '$\frac{kJ}{kg}$';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Editable = 'off';
            app.frackgminLabel = uilabel(app.UIFigure);
            app.frackgminLabel.Interpreter = 'latex';
            app.frackgminLabel.Text = '$\frac{kg}{min}$';
            app.HeatLossEditFieldLabel = uilabel(app.UIFigure);
            app.HeatLossEditFieldLabel.Text = 'Heat Loss';
            app.HeatLossEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeatLossEditField.Editable = 'off';
            app.WLabel_2 = uilabel(app.UIFigure);
            app.WLabel_2.Interpreter = 'latex';
            app.WLabel_2.Text = 'W';
            app.ExportValuesButton = uibutton(app.UIFigure, 'push');
            app.ExportValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportValuesButtonPushed, true);
            app.ExportValuesButton.Text = 'Export Values';
            app.barLabel = uilabel(app.UIFigure);
            app.barLabel.Interpreter = 'latex';
            app.barLabel.Text = 'bar';
            app.PressureEditFieldLabel = uilabel(app.UIFigure);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TinCLabel = uilabel(app.UIFigure);
            app.TinCLabel.Interpreter = 'latex';
            app.TinCLabel.Text = '$T\; in\; °C$';
            app.dotqinfrackWm2Label = uilabel(app.UIFigure);
            app.dotqinfrackWm2Label.Interpreter = 'latex';
            app.dotqinfrackWm2Label.Text = '$\dot{q}\; in\; \frac{kW}{m^2}$';
            app.HTCinfrackWm2KLabel = uilabel(app.UIFigure);
            app.HTCinfrackWm2KLabel.Interpreter = 'latex';
            app.HTCinfrackWm2KLabel.Text = '$HTC\; in\; \frac{kW}{m^2K}$$';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'vert', 'horz'};
            app.Switch.Value = 'vert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LOPER_Summary(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end